classdef VigenereGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleVigenereUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        CryptographicLabel      matlab.ui.control.Label
        PlainTextField          matlab.ui.control.EditField
        KeyField                matlab.ui.control.EditField
        CryptographicField      matlab.ui.control.EditField
        KeyLabel                matlab.ui.control.Label
        PlainTextLabel          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changing function: PlainTextField
        function PlainTextFieldValueChanging(app, event)
            changingValue = lower(event.Value);
            if (length(changingValue) > length(app.KeyField.Value) && ~isempty(app.KeyField.Value))
                app.CryptographicField.Value = encryptLowerCaseText(changingValue,app.KeyField.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleVigenereUIFigure = uifigure('Visible', 'off');
            app.SimpleVigenereUIFigure.Name = 'Simple Vigenere';
            app.SimpleVigenereUIFigure.Icon = 'icon.png';
            app.GridLayout = uigridlayout(app.SimpleVigenereUIFigure);
            app.PlainTextLabel = uilabel(app.GridLayout);
            app.PlainTextLabel.Text = 'Plain Text';
            app.KeyLabel = uilabel(app.GridLayout);
            app.KeyLabel.Text = 'Key';
            app.CryptographicField = uieditfield(app.GridLayout, 'text');
            app.CryptographicField.Editable = 'off';
            app.CryptographicField.Placeholder = 'Encrypted text will appear here';
            app.KeyField = uieditfield(app.GridLayout, 'text');
            app.PlainTextField = uieditfield(app.GridLayout, 'text');
            app.PlainTextField.ValueChangingFcn = createCallbackFcn(app, @PlainTextFieldValueChanging, true);
            app.CryptographicLabel = uilabel(app.GridLayout);
            app.CryptographicLabel.Text = 'Cryptographic text';
            % Show the figure after all components are created
            app.SimpleVigenereUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VigenereGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleVigenereUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleVigenereUIFigure)
        end
    end
end